classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel_2                      matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        PlotTab                      matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        ConMatrixTab                 matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        DataTab                      matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        UpdateTab                    matlab.ui.container.Tab
        UpdateLDAPanel               matlab.ui.container.Panel
        TrainButton                  matlab.ui.control.Button
        OpenButton_3                 matlab.ui.control.Button
        EditField_5                  matlab.ui.control.EditField
        Panel                        matlab.ui.container.Panel
        TrainedDataPanel             matlab.ui.container.Panel
        OpenButton_4                 matlab.ui.control.Button
        EditField_6                  matlab.ui.control.EditField
        SelectButton                 matlab.ui.control.Button
        ManualClassificationPanel    matlab.ui.container.Panel
        EditField_7                  matlab.ui.control.EditField
        JENISTEMBAKAULabel_2         matlab.ui.control.Label
        GetButton                    matlab.ui.control.Button
        ClassifyButton               matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        PortDropDown                 matlab.ui.control.DropDown
        PortDropDownLabel            matlab.ui.control.Label
        EditField_4                  matlab.ui.control.EditField
        HASILPREDIKSITEMBAKAULabel   matlab.ui.control.Label
        CO2EditField                 matlab.ui.control.NumericEditField
        CO2EditFieldLabel            matlab.ui.control.Label
        NOEditField                  matlab.ui.control.NumericEditField
        NOEditFieldLabel             matlab.ui.control.Label
        COEditField                  matlab.ui.control.NumericEditField
        COEditFieldLabel             matlab.ui.control.Label
        DataSelectionPanel           matlab.ui.container.Panel
        SelectDataDropDown           matlab.ui.control.DropDown
        SelectDataDropDownLabel      matlab.ui.control.Label
        PlotSelectionPanel           matlab.ui.container.Panel
        JenisTembakau2DropDown       matlab.ui.control.DropDown
        JenisTembakau2DropDownLabel  matlab.ui.control.Label
        JenisTembakau1DropDown       matlab.ui.control.DropDown
        JenisTembakau1DropDownLabel  matlab.ui.control.Label
        TestingFilePanel             matlab.ui.container.Panel
        TestButton                   matlab.ui.control.Button
        OpenButton_2                 matlab.ui.control.Button
        EditField_3                  matlab.ui.control.EditField
        DETEKSITEMBAKAULabel         matlab.ui.control.Label
    end

  
    properties (Access = private)
        y
        X_lda
        md1
        MdlLinear
        w_matrix
        Ds
        Vs
        training_data
        testing_data
        testing_pred
        euc_dist
        ports
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('trained.mat');
            app.X_lda = xLda;
            app.Ds = eValue;
            app.Vs = eVector;
            app.w_matrix = wMatrix;
            app.y = yData;
            app.training_data =trainData ;
            app.MdlLinear = MdlLinearData ;
            pred = app.MdlLinear.predict(app.X_lda);
            Ibad = ~strcmp(pred,app.y);
            
            gscatter(app.UIAxes,app.X_lda(:,1),app.X_lda(:,2),app.y,'krbgmc','ov^+xs');
            app.UIAxes.LineWidth = 2;
            hold(app.UIAxes,'on');
            
            app.MdlLinear = fitcdiscr(app.X_lda,app.y);
            
            for i = 1:6
                temp = app.MdlLinear.ClassNames([3 i]);
                K = app.MdlLinear.Coeffs(3,i).Const;
                L = app.MdlLinear.Coeffs(3,i).Linear;
            
                f = @(x1,x2) K + L(1)*x1 + L(2)*x2;
                fimplicit(app.UIAxes,f,[0 700 0 1400]);
                app.UIAxes.LineWidth = 2;
                
            end
            hold(app.UIAxes,'off');
            
            
            miss_classification_rate = sum(Ibad)/numel(app.y);
            
            cm = confusionchart(app.y,pred);
            
            cm.RowSummary = 'row-normalized';
            cm.ColumnSummary = 'column-normalized';
            saveas(cm,'cmatrix.png');
            close;
            imshow('cmatrix.png', 'Parent', app.UIAxes2); 
        end

        % Button pushed function: OpenButton_3
        function OpenButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.xlsx;*.xls;*.csv'};
            [f, p] = uigetfile(filterspec);
            if p
                app.EditField_5.Value = append(p,f);
            else
                app.EditField_5.Value = 'none';
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            [ndata, text, ~] = xlsread(app.EditField_5.Value,'training'); % memanggil data excel training
            [~, app.y] = xlsread('training.xlsx','label'); % memanggil label dari tiap data
            target_name = {'darmawangi';'besuki';'banyuwangi';'kalituri';'kanyumas';'prancak'}; % merupakan label tiap kelas
            
            [row1,~] = size(target_name); %row1 merupakan banyaknya kelas
            [row2,col2] = size(ndata); %row2 merupakan banyaknya data ciri; col2 merupakan nilai yang dipakai untuk penyesuaian matriks pada perhitungan 
            
            n = row2/row1; %n merupakan banyaknya data ciri tiap kelas
         
            header = {'class','CO','NO','CO2'};
            Torigin = table(app.y,ndata);
            app.UITable.Data = Torigin;
            
            
            % init
            class_feature_mean = zeros(6,3);
            data_min_cfm = zeros(300,3);
            between_class_scatter_matrix = cell([1 6]);
            within_class_scatter_matrix = cell([300 1]);
            between_class = zeros(3,3);
            within_class_scatter = zeros(3,3);
                        
            %% between-class scatter matrix
            % menghitung rata - rata seluruh ciri 
            for i = 1:col2
                xmean(i) = mean(ndata(:,i)); 
            end
            
            % menghitung rata - rata seluruh ciri tiap kelas
            df = [text {'class'};num2cell(ndata) app.y]; % pengelompokan data ciri dan label
            
            for i = 1 : row1
                for j = 1 : row2
                    if (isequal(target_name{i},df{j+1,4}))
                        class_feature_mean(i,1) = class_feature_mean(i,1)+df{j+1,1};
                        class_feature_mean(i,2) = class_feature_mean(i,2)+df{j+1,2};
                        class_feature_mean(i,3) = class_feature_mean(i,3)+df{j+1,3};
                    end
                end
            end
            class_feature_mean = class_feature_mean/(row2/row1);
            
            % menghitung sb tiap kelas
            for i = 1:row1
                between_class_scatter_matrix{i} = 50*((class_feature_mean(i,:)-xmean).*(class_feature_mean(i,:)-xmean)');
                between_class = between_class + between_class_scatter_matrix{i};
            end
            
            %% within-class scatter matrix
            
            for i = 1 : row1
                for j = 1 : row2
                    if (isequal(target_name{i},df{j+1,4}))
                        data_min_cfm(j,:) = ndata(j,:) - class_feature_mean(i,:);
                        within_class_scatter_matrix{j} = data_min_cfm(j,:).* data_min_cfm(j,:)';
                        within_class_scatter = within_class_scatter + within_class_scatter_matrix{j};
                    end
                end
            end
            
            %% eigenvalue, eigenvector 
            [eigen_vectors,eigen_values] = eig(inv(within_class_scatter)*(between_class));
            
            [d,ind] = sort(diag(eigen_values),'descend');
            
            app.Ds = eigen_values(ind,ind);
            app.Vs = eigen_vectors(:,ind);
            
            app.w_matrix = [app.Vs(:,1) app.Vs(:,2)];
            
            %% new plane
            app.X_lda = ndata*app.w_matrix;
            
            %% plotting result
            
            gscatter(app.UIAxes,app.X_lda(:,1),app.X_lda(:,2),app.y,'krbgmc','ov^+xs');
            app.UIAxes.LineWidth = 2;
            hold(app.UIAxes,'on');
            
            app.MdlLinear = fitcdiscr(app.X_lda,app.y);
            
            for i = 1:6
                temp = app.MdlLinear.ClassNames([3 i]);
                K = app.MdlLinear.Coeffs(3,i).Const;
                L = app.MdlLinear.Coeffs(3,i).Linear;
            
                f = @(x1,x2) K + L(1)*x1 + L(2)*x2;
                fimplicit(app.UIAxes,f,[0 700 0 1400]);
                app.UIAxes.LineWidth = 2;
                
            end
            hold(app.UIAxes,'off');
            
            %% Classification Error
            pred = app.MdlLinear.predict(app.X_lda);
            Ibad = ~strcmp(pred,app.y);
            
            miss_classification_rate = sum(Ibad)/numel(app.y);
            
            cm = confusionchart(app.y,pred);
            
            cm.RowSummary = 'row-normalized';
            cm.ColumnSummary = 'column-normalized';
            saveas(cm,'cmatrix.png');
            close;
            imshow('cmatrix.png', 'Parent', app.UIAxes2); 
            
            app.training_data = table(app.y,ndata);
            
            answer  = questdlg('Would you like to save your trainned data ?', 'Trainned Data', 'yes','no','no');
            
            xLda = app.X_lda;
            eValue = app.Ds;
            eVector = app.Vs;
            wMatrix = app.w_matrix;
            yData = app.y;
            trainData = app.training_data;
            MdlLinearData = app.MdlLinear;
    
            switch answer 
                case 'no'
                    disp('lda not updated!');
                case 'yes'
                    save trained.mat xLda eValue eVector wMatrix yData MdlLinearData trainData 
                    disp('your lda is updated with new trained data!');
                    
            end
        end

        % Value changed function: JenisTembakau2DropDown
        function JenisTembakau2DropDownValueChanged(app, event)
            value1 = app.JenisTembakau1DropDown.Value;
            value2 = app.JenisTembakau2DropDown.Value;
            plot2Data(value1,value2);
            
            function plot2Data(name1,name2)
                T = table(app.y,app.X_lda);
                TF = (strcmp(T.Var1,name1)|strcmp(T.Var1,name2));
                T_selected = T(TF,:);
                
                T1 = table(app.y,app.X_lda);
                TF1 = strcmp(T1.Var1,name1);
                T_selected1 = T1(TF1,:);
                
                T2 = table(app.y,app.X_lda);
                TF2 = strcmp(T2.Var1,name2);
                T_selected2 = T2(TF2,:)
                
                eucDist = pdist2(T_selected1.Var2, T_selected2.Var2);
                app.euc_dist =eucDist;
                app.UITable.Data = table(T_selected1.Var2, T_selected2.Var2,app.euc_dist(:,1),app.euc_dist(:,2));
              
            
                %% plotting result
                gscatter(app.UIAxes,T_selected.Var2(:,1),T_selected.Var2(:,2),T_selected.Var1,'rb','ov');
                app.UIAxes.LineWidth = 2;
                hold(app.UIAxes,'on')
                
                app.MdlLinear = fitcdiscr(T_selected.Var2,T_selected.Var1);
                
                
                    temp = app.MdlLinear.ClassNames([1 2]);
                    K = app.MdlLinear.Coeffs(1,2).Const;  
                    L = app.MdlLinear.Coeffs(1,2).Linear;
                
                    f = @(x1,x2) K + L(1)*x1 + L(2)*x2;
                    fimplicit(app.UIAxes,f,[0 700 0 1400]);
                    app.UIAxes.LineWidth = 2;
                    
                hold(app.UIAxes,'off')
                
            end
        end

        % Value changed function: JenisTembakau1DropDown
        function JenisTembakau1DropDownValueChanged(app, event)
            value1 = app.JenisTembakau1DropDown.Value;
            value2 = app.JenisTembakau2DropDown.Value;
            app.JenisTembakau1DropDown.Items;
            initial = "All";
            
            if value1 == initial
                app.JenisTembakau2DropDown.Enable = false;
                plotAll();
            else
                app.JenisTembakau2DropDown.Enable = true;
                plot2Data(value1,value2)
            end
            
            function plotAll()
            gscatter(app.UIAxes,app.X_lda(:,1),app.X_lda(:,2),app.y,'krbgmc','ov^+xs');
            app.UIAxes.LineWidth = 2;
            hold(app.UIAxes,'on');
            
            app.MdlLinear = fitcdiscr(app.X_lda,app.y);
            
            for i = 1:6
                temp = app.MdlLinear.ClassNames([3 i])
                K = app.MdlLinear.Coeffs(3,i).Const  
                L = app.MdlLinear.Coeffs(3,i).Linear;
                f = @(x1,x2) K + L(1)*x1 + L(2)*x2;
                fimplicit(app.UIAxes,f,[0 700 0 1400]);
                app.UIAxes.LineWidth = 2;
                
            end
            hold(app.UIAxes,'off')
            end
            
            function plot2Data(name1,name2)
                
                T = table(app.y,app.X_lda);
                TF = (strcmp(T.Var1,name1)|strcmp(T.Var1,name2));
                T_selected = T(TF,:);
                
                T1 = table(app.y,app.X_lda);
                TF1 = strcmp(T1.Var1,name1);
                T_selected1 = T1(TF1,:);
                
                T2 = table(app.y,app.X_lda);
                TF2 = strcmp(T2.Var1,name2);
                T_selected2 = T2(TF2,:);
                
                eucDist = pdist2(T_selected1.Var2, T_selected2.Var2);
                app.euc_dist =eucDist;
                app.UITable.Data = table(T_selected1.Var2, T_selected2.Var2,app.euc_dist(:,1),app.euc_dist(:,2));
                
                %% plotting result
                gscatter(app.UIAxes,T_selected.Var2(:,1),T_selected.Var2(:,2),T_selected.Var1,'rb','ov')
                app.UIAxes.LineWidth = 2;
                hold(app.UIAxes,'on')
                
                app.MdlLinear = fitcdiscr(T_selected.Var2,T_selected.Var1);
                
                    temp = app.MdlLinear.ClassNames([1 2]);
                    K = app.MdlLinear.Coeffs(1,2).Const;
                    L = app.MdlLinear.Coeffs(1,2).Linear;
                
                    f = @(x1,x2) K + L(1)*x1 + L(2)*x2;
                    fimplicit(app.UIAxes,f,[0 700 0 1400]);
                    app.UIAxes.LineWidth = 2;
                    
                hold(app.UIAxes,'off')
                
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            [ndata, ~, ~] = xlsread(app.EditField_3.Value,'testing');
             %% new plane
            app.X_lda = ndata*app.w_matrix;
            [~, app.y] = xlsread(app.EditField_3.Value,'label');
            %% plotting result
            gscatter(app.UIAxes,app.X_lda(:,1),app.X_lda(:,2),app.y,'krbgmc','ov^+xs');
%             app.UIAxes.LineWidth(2);
%             app.UIAxes.XLabel(['new plane x']);
%             app.UIAxes.YLabel(['new plan y']);
            hold(app.UIAxes,'on')
            
            app.MdlLinear = fitcdiscr(app.X_lda,app.y);
            
            for i = 1:6
                temp = app.MdlLinear.ClassNames([3 i]);
                K = app.MdlLinear.Coeffs(3,i).Const;  
                L = app.MdlLinear.Coeffs(3,i).Linear;
            
                f = @(x1,x2) K + L(1)*x1 + L(2)*x2;
                fimplicit(app.UIAxes,f,[0 700 0 1400]);
             
%                 app.UIAxes.LineWidth(2)
%                 app.UIAxes.Legend(['Boundary between ' temp{1} '&' temp{2}])
            end
            hold(app.UIAxes,'off')
            
            %% Classification Error
            pred = app.MdlLinear.predict(app.X_lda);
            
            Ibad = ~strcmp(pred,app.y);
            
            
            miss_classification_rate = sum(Ibad)/numel(app.y);
            
            cm = confusionchart(app.y,pred);
            
            cm.RowSummary = 'row-normalized';
            cm.ColumnSummary = 'column-normalized';
            saveas(cm,'cmatrix.png');
            close;
            imshow('cmatrix.png', 'Parent', app.UIAxes2); 
            
            app.testing_data = table(app.y,ndata);
            varNamesPred = {'Target','Prediction';};
            app.testing_pred = table(app.y,pred,ndata);
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            valueCO = app.COEditField.Value;
            valueNO = app.NOEditField.Value;
            valueCO2 = app.CO2EditField.Value;
            temp = [valueCO valueNO valueCO2];
            val = temp*app.w_matrix;
            Var2 = app.MdlLinear.predict(val);
            app.EditField_4.Value = Var2{1};
            Tcell = table({app.EditField_7.Value},Var2,[valueCO valueNO valueCO2])
            app.testing_pred = [Tcell;app.testing_pred]
        end

        % Value changed function: SelectDataDropDown
        function SelectDataDropDownValueChanged(app, event)
            value = app.SelectDataDropDown.Value;
            varNames = {'euclidean distance x','euclidean distance y'};
            switch value
                case 'training data'
                    app.UITable.Data = app.training_data;
                case 'testing data'
                    app.UITable.Data = app.testing_data;
                case 'classification result'
                    app.UITable.Data = app.testing_pred;
                case 'eigen vector'
                    app.UITable.Data = table(app.Vs);
                case 'eigen value'
                    app.UITable.Data = table(app.Ds);
                case 'euclidean distance'
                    app.UITable.Data = table(app.euc_dist(:,1),app.euc_dist(:,2));
                otherwise
                    app.UITable.Data = app.training_data;
            end
        end

        % Drop down opening function: JenisTembakau1DropDown
        function JenisTembakau1DropDownOpening(app, event)
            
        end

        % Drop down opening function: JenisTembakau2DropDown
        function JenisTembakau2DropDownOpening(app, event)
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
        end

        % Value changed function: EditField_5
        function EditFieldValueChanged(app, event)
            value = app.EditField_5.Value;
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            value = app.EditField_6.Value;
            load(value);
            app.X_lda = xLda;
            app.Ds = eValue;
            app.Vs = eVector;
            app.w_matrix = wMatrix;
            app.y = yData;
            app.training_data =trainData ;
            app.MdlLinear = MdlLinearData ;
            pred = app.MdlLinear.predict(app.X_lda);
            Ibad = ~strcmp(pred,app.y);
            
            gscatter(app.UIAxes,app.X_lda(:,1),app.X_lda(:,2),app.y,'krbgmc','ov^+xs');
            app.UIAxes.LineWidth = 2;
            hold(app.UIAxes,'on');
            
            app.MdlLinear = fitcdiscr(app.X_lda,app.y);
            
            for i = 1:6
                temp = app.MdlLinear.ClassNames([3 i]);
                K = app.MdlLinear.Coeffs(3,i).Const;
                L = app.MdlLinear.Coeffs(3,i).Linear;
            
                f = @(x1,x2) K + L(1)*x1 + L(2)*x2;
                fimplicit(app.UIAxes,f,[0 700 0 1400]);
                app.UIAxes.LineWidth = 2;
                
            end
            hold(app.UIAxes,'off');       
            
            miss_classification_rate = sum(Ibad)/numel(app.y);
            
            cm = confusionchart(app.y,pred);
            
            cm.RowSummary = 'row-normalized';
            cm.ColumnSummary = 'column-normalized';
            saveas(cm,'cmatrix.png');
            close;
            imshow('cmatrix.png', 'Parent', app.UIAxes2); 
        end

        % Button pushed function: OpenButton_4
        function OpenButton_4Pushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            if p
                app.EditField_6.Value = append(p,f);
            else
                app.EditField_6.Value = 'none';
            end
        end

        % Button pushed function: OpenButton_2
        function OpenButton_2Pushed(app, event)
            filterspec = {'*.xlsx;*.xls;*.csv'};
            [f, p] = uigetfile(filterspec);
            if p
                app.EditField_3.Value = append(p,f);
            else
                app.EditField_3.Value = 'none';
            end
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            value = app.PortDropDown.Value;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            ports = serialportlist("available")
            app.PortDropDown.Items = [ports]
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
%             arduinoObj = arduino(app.PortDropDown.Value,"Uno","Libraries",{'SPI','Serial','I2C'});
%             serialdevObj = device(arduinoObj,'SerialPort',1,'BaudRate', 115200, 'DataBits',8, 'StopBits', 1, 'Parity', 'even')
%             numBytes = serialdevObj.NumBytesAvailable
%             read(serialdevObj,numBytes)
%             clear arduinoObj, serialdevObj
%             a = arduino(app.PortDropDown.Value,'Uno');
            
            a = arduino(app.PortDropDown.Value,'due','libraries','ExampleLCD/LCDAddon','ForceBuildOn',true);
            lcd = addon(a,'ExampleLCD/LCDAddon','RegisterSelectPin','D7','EnablePin','D6','DataPins',{'D5','D4','D3','D2'});
            
            clearLCD(lcd);
            
            value1 = readVoltage(a,'A0')
            value2 = readVoltage(a,'A1')
            value3 = readVoltage(a,'A2')
            
            initializeLCD(lcd);
            
            app.COEditField.Value = value1
            app.NOEditField.Value = value2
            app.CO2EditField.Value = value3
            
            printLCD(lcd,'CO = '+ value1 + "|NO = "+ value2 + "|CO2 = " + value3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.DETEKSITEMBAKAULabel = uilabel(app.Panel);
            app.DETEKSITEMBAKAULabel.Text = 'DETEKSI TEMBAKAU';
            app.TestingFilePanel = uipanel(app.Panel);
            app.TestingFilePanel.Title = 'Testing File';
            app.EditField_3 = uieditfield(app.TestingFilePanel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.OpenButton_2 = uibutton(app.TestingFilePanel, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_2Pushed, true);
            app.OpenButton_2.Text = 'Open';
            app.TestButton = uibutton(app.TestingFilePanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.PlotSelectionPanel = uipanel(app.Panel);
            app.PlotSelectionPanel.Title = 'Plot Selection';
            app.JenisTembakau1DropDownLabel = uilabel(app.PlotSelectionPanel);
            app.JenisTembakau1DropDownLabel.Text = 'Jenis Tembakau 1';
            app.JenisTembakau1DropDown = uidropdown(app.PlotSelectionPanel);
            app.JenisTembakau1DropDown.Items = {'All', 'darmawangi', 'besuki', 'banyuwangi', 'kalituri', 'kanyumas', 'prancak'};
            app.JenisTembakau1DropDown.DropDownOpeningFcn = createCallbackFcn(app, @JenisTembakau1DropDownOpening, true);
            app.JenisTembakau1DropDown.ValueChangedFcn = createCallbackFcn(app, @JenisTembakau1DropDownValueChanged, true);
            app.JenisTembakau1DropDown.Value = 'All';
            app.JenisTembakau2DropDownLabel = uilabel(app.PlotSelectionPanel);
            app.JenisTembakau2DropDownLabel.Text = 'Jenis Tembakau 2';
            app.JenisTembakau2DropDown = uidropdown(app.PlotSelectionPanel);
            app.JenisTembakau2DropDown.Items = {'darmawangi', 'besuki', 'banyuwangi', 'kalituri', 'kanyumas', 'prancak'};
            app.JenisTembakau2DropDown.DropDownOpeningFcn = createCallbackFcn(app, @JenisTembakau2DropDownOpening, true);
            app.JenisTembakau2DropDown.ValueChangedFcn = createCallbackFcn(app, @JenisTembakau2DropDownValueChanged, true);
            app.JenisTembakau2DropDown.Enable = 'off';
            app.JenisTembakau2DropDown.Value = 'besuki';
            app.DataSelectionPanel = uipanel(app.Panel);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.SelectDataDropDownLabel = uilabel(app.DataSelectionPanel);
            app.SelectDataDropDownLabel.Text = 'Select Data';
            app.SelectDataDropDown = uidropdown(app.DataSelectionPanel);
            app.SelectDataDropDown.Items = {'training data', 'testing data', 'classification result', 'eigen vector', 'eigen value', 'euclidean distance'};
            app.SelectDataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDataDropDownValueChanged, true);
            app.SelectDataDropDown.Value = 'training data';
            app.ManualClassificationPanel = uipanel(app.Panel);
            app.ManualClassificationPanel.Title = 'Manual Classification';
            app.COEditFieldLabel = uilabel(app.ManualClassificationPanel);
            app.COEditFieldLabel.Text = 'CO';
            app.COEditField = uieditfield(app.ManualClassificationPanel, 'numeric');
            app.NOEditFieldLabel = uilabel(app.ManualClassificationPanel);
            app.NOEditFieldLabel.Text = 'NO';
            app.NOEditField = uieditfield(app.ManualClassificationPanel, 'numeric');
            app.CO2EditFieldLabel = uilabel(app.ManualClassificationPanel);
            app.CO2EditFieldLabel.Text = 'CO2';
            app.CO2EditField = uieditfield(app.ManualClassificationPanel, 'numeric');
            app.HASILPREDIKSITEMBAKAULabel = uilabel(app.ManualClassificationPanel);
            app.HASILPREDIKSITEMBAKAULabel.Text = 'HASIL PREDIKSI TEMBAKAU';
            app.EditField_4 = uieditfield(app.ManualClassificationPanel, 'text');
            app.EditField_4.Editable = 'off';
            app.PortDropDownLabel = uilabel(app.ManualClassificationPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ManualClassificationPanel);
            app.PortDropDown.Items = {'default'};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = 'default';
            app.ConnectButton = uibutton(app.ManualClassificationPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ClassifyButton = uibutton(app.ManualClassificationPanel, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.GetButton = uibutton(app.ManualClassificationPanel, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.JENISTEMBAKAULabel_2 = uilabel(app.ManualClassificationPanel);
            app.JENISTEMBAKAULabel_2.Text = 'JENIS TEMBAKAU';
            app.EditField_7 = uieditfield(app.ManualClassificationPanel, 'text');
            app.TrainedDataPanel = uipanel(app.Panel);
            app.TrainedDataPanel.Title = 'Trained Data';
            app.SelectButton = uibutton(app.TrainedDataPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.EditField_6 = uieditfield(app.TrainedDataPanel, 'text');
            app.OpenButton_4 = uibutton(app.TrainedDataPanel, 'push');
            app.OpenButton_4.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_4Pushed, true);
            app.OpenButton_4.Text = 'Open';
            app.Panel_2 = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.Panel_2);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConMatrixTab = uitab(app.TabGroup);
            app.ConMatrixTab.Title = 'Con Matrix';
            app.UIAxes2 = uiaxes(app.ConMatrixTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UpdateTab = uitab(app.TabGroup);
            app.UpdateTab.Title = 'Update';
            app.UpdateLDAPanel = uipanel(app.UpdateTab);
            app.UpdateLDAPanel.Title = 'Update LDA';
            app.EditField_5 = uieditfield(app.UpdateLDAPanel, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.OpenButton_3 = uibutton(app.UpdateLDAPanel, 'push');
            app.OpenButton_3.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton_3.Text = 'Open';
            app.TrainButton = uibutton(app.UpdateLDAPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end